classdef XRayApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportPanel                     matlab.ui.container.Panel
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilepathEditField2              matlab.ui.control.EditField
        FilepathEditField_2Label        matlab.ui.control.Label
        ExportButton                    matlab.ui.control.Button
        IncludeimporteddataCheckBox     matlab.ui.control.CheckBox
        SamplePanel                     matlab.ui.container.Panel
        AzimuthstddevdegEditField       matlab.ui.control.EditField
        AzimuthstddevdegEditFieldLabel  matlab.ui.control.Label
        TiltstddevdegEditField          matlab.ui.control.EditField
        TiltstddevdegEditFieldLabel     matlab.ui.control.Label
        MosaiccrystalpropertiesLabel    matlab.ui.control.Label
        SLrepeatsEditField              matlab.ui.control.EditField
        SLrepeatsEditFieldLabel         matlab.ui.control.Label
        RelaxationEditField             matlab.ui.control.EditField
        RelaxationEditFieldLabel        matlab.ui.control.Label
        EpilayertiltdegEditField        matlab.ui.control.EditField
        EpilayertiltdegEditFieldLabel   matlab.ui.control.Label
        ThicknessnmEditField            matlab.ui.control.EditField
        ThicknessnmEditFieldLabel       matlab.ui.control.Label
        LayerpropertiesLabel            matlab.ui.control.Label
        InmolefractionEditField         matlab.ui.control.EditField
        InmolefractionEditFieldLabel    matlab.ui.control.Label
        GamolefractionEditField         matlab.ui.control.EditField
        GamolefractionEditFieldLabel    matlab.ui.control.Label
        AlmolefractionEditField         matlab.ui.control.EditField
        AlmolefractionEditFieldLabel    matlab.ui.control.Label
        InterfaceoffsetlayerCheckBox    matlab.ui.control.CheckBox
        CationcompositionLabel          matlab.ui.control.Label
        SamplestackupListBox            matlab.ui.control.ListBox
        SamplestackupListBoxLabel       matlab.ui.control.Label
        Addtoworkspace                  matlab.ui.control.Button
        AddSLButton                     matlab.ui.control.Button
        RemovelayerButton               matlab.ui.control.Button
        AddlayerButton                  matlab.ui.control.Button
        SimulatePanel                   matlab.ui.container.Panel
        OffsetDataEditField             matlab.ui.control.NumericEditField
        OffsetdatadegLabel              matlab.ui.control.Label
        DoneLamp                        matlab.ui.control.Lamp
        DoneLampLabel                   matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeauLabel                matlab.ui.control.Label
        XraywavelengthAngstromsEditField  matlab.ui.control.NumericEditField
        XraywavelengthAngstromsEditFieldLabel  matlab.ui.control.Label
        DiffractionplanehklEditField    matlab.ui.control.EditField
        DiffractionplanehklEditFieldLabel  matlab.ui.control.Label
        AzimuthwrtoffcutdegEditField    matlab.ui.control.NumericEditField
        AzimuthwrtoffcutdegEditFieldLabel  matlab.ui.control.Label
        OffcutdegEditField              matlab.ui.control.NumericEditField
        OffcutdegEditFieldLabel         matlab.ui.control.Label
        SubstrateorientationuvwEditField  matlab.ui.control.EditField
        SubstrateorientationuvwEditFieldLabel  matlab.ui.control.Label
        MosaiccrystalparametersPanel    matlab.ui.container.Panel
        AzimuthdistributionDropDown     matlab.ui.control.DropDown
        AzimuthdistributionDropDownLabel  matlab.ui.control.Label
        TiltdistributionDropDown        matlab.ui.control.DropDown
        TiltdistributionDropDownLabel   matlab.ui.control.Label
        AzimuthcrystallitesEditField    matlab.ui.control.NumericEditField
        AzimuthcrystallitesEditFieldLabel  matlab.ui.control.Label
        TiltcrystallitesEditField       matlab.ui.control.NumericEditField
        TiltcrystallitesEditFieldLabel  matlab.ui.control.Label
        StandarddeviationsEditField     matlab.ui.control.NumericEditField
        StandarddeviationsEditFieldLabel  matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        OmegaincidentXrayanglePanel     matlab.ui.container.Panel
        TwoTheta                        matlab.ui.control.NumericEditField
        ThetadegLabel                   matlab.ui.control.Label
        ResolutiondegEditField          matlab.ui.control.NumericEditField
        ResolutiondegEditFieldLabel     matlab.ui.control.Label
        EnddegEditField                 matlab.ui.control.NumericEditField
        EnddegEditFieldLabel            matlab.ui.control.Label
        StartdegEditField               matlab.ui.control.NumericEditField
        StartdegEditFieldLabel          matlab.ui.control.Label
        ScantypeButtonGroup             matlab.ui.container.ButtonGroup
        RockingButton                   matlab.ui.control.ToggleButton
        Omega2ThetaButton               matlab.ui.control.ToggleButton
        MonochromatorPanel              matlab.ui.container.Panel
        BouncesEditField                matlab.ui.control.NumericEditField
        BouncesEditFieldLabel           matlab.ui.control.Label
        AngledegEditField               matlab.ui.control.NumericEditField
        AngledegEditFieldLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ImportPanel                     matlab.ui.container.Panel
        FilepathEditField               matlab.ui.control.EditField
        FilepathEditFieldLabel          matlab.ui.control.Label
        ClearData                       matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        AddtoworkspaceButton2           matlab.ui.control.Button
        ExcelfilesxlsxlsxPanel          matlab.ui.container.Panel
        RangeEditField                  matlab.ui.control.EditField
        RangeEditFieldLabel             matlab.ui.control.Label
        SheetEditField                  matlab.ui.control.EditField
        SheetEditFieldLabel             matlab.ui.control.Label
        FiletypeButtonGroup             matlab.ui.container.ButtonGroup
        TexttxtcsvButton                matlab.ui.control.ToggleButton
        ExcelxlsxlsxButton              matlab.ui.control.ToggleButton
        ContextMenu                     matlab.ui.container.ContextMenu
        PopoutfigureMenu                matlab.ui.container.Menu
    end

    
    properties (Access = public)
        n = 0;              % Layer counter
        CurrLayer = 0;      % Currently selected layer
        
        sample = struct('siteAatoms', struct('Al2', 0, 'Ga2', 1, 'In2', 0),...
                        'siteBatoms', struct('O3', 1),...
                        'thickness', 600000,...
                        'delphi', 0,...
                        'sigma_alpha', 0,...
                        'sigma_beta', 0,...
                        'relaxation', 0,...
                        'repeats', 1,...
                        'layertype', 'single');
        sampleout = {};
        x = 0;
        y = 0;
        omega = 0;
        I = 0;
        I0 = 0;
        lamella = {};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SamplestackupListBox
        function SamplestackupListBoxValueChanged(app, event)
            app.CurrLayer = app.SamplestackupListBox.Value;
            app.AlmolefractionEditField.Value = num2str(app.sample(app.CurrLayer+1).siteAatoms.Al2);
            app.GamolefractionEditField.Value = num2str(app.sample(app.CurrLayer+1).siteAatoms.Ga2);
            app.InmolefractionEditField.Value = num2str(app.sample(app.CurrLayer+1).siteAatoms.In2);
            app.ThicknessnmEditField.Value = num2str(app.sample(app.CurrLayer+1).thickness);
            app.SLrepeatsEditField.Value = num2str(app.sample(app.CurrLayer+1).repeats);
            app.EpilayertiltdegEditField.Value = num2str(app.sample(app.CurrLayer+1).delphi);
            app.TiltstddevdegEditField.Value = num2str(app.sample(app.CurrLayer+1).sigma_alpha);
            app.AzimuthstddevdegEditField.Value = num2str(app.sample(app.CurrLayer+1).sigma_beta);
            app.RelaxationEditField.Value = num2str(app.sample(app.CurrLayer+1).relaxation);
            if(strcmp(app.sample(app.CurrLayer+1).layertype, 'offset'))
                app.InterfaceoffsetlayerCheckBox.Value = 1;
            else
                app.InterfaceoffsetlayerCheckBox.Value = 0;
            end
        end

        % Button pushed function: AddlayerButton
        function AddlayerButtonPushed(app, event)
            app.n = app.n + 1;          % Increment layer count
            app.CurrLayer = app.n;
            app.SamplestackupListBox.Items = [app.SamplestackupListBox.Items {'Single layer'}];
            app.SamplestackupListBox.ItemsData = [app.SamplestackupListBox.ItemsData app.n];
            app.SamplestackupListBox.Value = app.n;
            
            app.sample(app.n+1) = app.sample(1);
            app.sample(app.n+1).layertype = 'single';
        end

        % Button pushed function: AddSLButton
        function AddSLButtonPushed(app, event)
            app.n = app.n + 2;          % Increment layer count
            app.CurrLayer = app.n;
            app.SamplestackupListBox.Items = [app.SamplestackupListBox.Items {'Superlattice layer 1'} {'Superlattice layer 2'}];
            app.SamplestackupListBox.ItemsData = [app.SamplestackupListBox.ItemsData app.n-1 app.n];
            app.SamplestackupListBox.Value = app.n;
            
            app.sample(app.n) = app.sample(1);
            app.sample(app.n+1) = app.sample(1);
            
            app.sample(app.n).layertype = 'SL1';
            app.sample(app.n+1).layertype = 'SL2';
        end

        % Button pushed function: RemovelayerButton
        function RemovelayerButtonPushed(app, event)
            if(app.n > 0)
                if(strcmp(app.sample(end).layertype, 'SL2'))
                    app.SamplestackupListBox.Items = app.SamplestackupListBox.Items(1:end-2);
                    app.SamplestackupListBox.ItemsData = app.SamplestackupListBox.ItemsData(1:end-2);
                    app.sample = app.sample(1:end-2);
                    app.n = app.n - 2;
                    app.CurrLayer = app.n;
                    app.SamplestackupListBox.Value = app.n;
                else
                    app.SamplestackupListBox.Items = app.SamplestackupListBox.Items(1:end-1);
                    app.SamplestackupListBox.ItemsData = app.SamplestackupListBox.ItemsData(1:end-1);
                    app.sample = app.sample(1:end-1);
                    app.n = app.n - 1;
                    app.CurrLayer = app.n;
                    app.SamplestackupListBox.Value = app.n;
                end
            end
        end

        % Button pushed function: Addtoworkspace
        function AddtoworkspaceButtonPushed(app, event)
            assignin('base', 'samplein', app.sample);
        end

        % Value changed function: AlmolefractionEditField
        function AlmolefractionEditFieldValueChanged(app, event)
            app.sample(app.CurrLayer+1).siteAatoms.Al2 = str2double(app.AlmolefractionEditField.Value);
            app.sample(app.CurrLayer+1).siteAatoms.Ga2 = 1 - app.sample(app.CurrLayer+1).siteAatoms.Al2 - app.sample(app.CurrLayer+1).siteAatoms.In2;
            app.GamolefractionEditField.Value = num2str(app.sample(app.CurrLayer+1).siteAatoms.Ga2);
        end

        % Value changed function: InmolefractionEditField
        function InmolefractionEditFieldValueChanged(app, event)
            app.sample(app.CurrLayer+1).siteAatoms.In2 = str2double(app.InmolefractionEditField.Value);
            app.sample(app.CurrLayer+1).siteAatoms.Ga2 = 1 - app.sample(app.CurrLayer+1).siteAatoms.Al2 - app.sample(app.CurrLayer+1).siteAatoms.In2;
            app.GamolefractionEditField.Value = num2str(app.sample(app.CurrLayer+1).siteAatoms.Ga2);
        end

        % Value changed function: ThicknessnmEditField
        function ThicknessnmEditFieldValueChanged(app, event)
            app.sample(app.CurrLayer+1).thickness = str2double(app.ThicknessnmEditField.Value);
        end

        % Value changed function: SLrepeatsEditField
        function SLrepeatsEditFieldValueChanged(app, event)
            if(strcmp(app.sample(app.CurrLayer+1).layertype, 'SL1'))
                app.sample(app.CurrLayer+1).repeats = str2double(app.SLrepeatsEditField.Value);
                app.sample(app.CurrLayer+2).repeats = str2double(app.SLrepeatsEditField.Value);
            elseif(strcmp(app.sample(app.CurrLayer+1).layertype, 'SL2'))
                app.sample(app.CurrLayer+1).repeats = str2double(app.SLrepeatsEditField.Value);
                app.sample(app.CurrLayer).repeats = str2double(app.SLrepeatsEditField.Value);
            else
                app.SLrepeatsEditField.Value = '1';
            end
        end

        % Value changed function: EpilayertiltdegEditField
        function EpilayertiltdegEditFieldValueChanged(app, event)
            app.sample(app.CurrLayer+1).delphi = str2double(app.EpilayertiltdegEditField.Value);
        end

        % Value changed function: InterfaceoffsetlayerCheckBox
        function InterfaceoffsetlayerCheckBoxValueChanged(app, event)
            value = app.InterfaceoffsetlayerCheckBox.Value;
            if(value)&&(strcmp(app.sample(app.CurrLayer+1).layertype, 'single'))
                app.sample(app.CurrLayer+1).layertype = 'offset';
            end
        end

        % Value changed function: RelaxationEditField
        function RelaxationEditFieldValueChanged(app, event)
            app.sample(app.CurrLayer+1).relaxation = str2double(app.RelaxationEditField.Value);
        end

        % Value changed function: TiltstddevdegEditField
        function TiltstddevdegEditFieldValueChanged(app, event)
            app.sample(app.CurrLayer+1).sigma_alpha = str2double(app.TiltstddevdegEditField.Value);
        end

        % Value changed function: AzimuthstddevdegEditField
        function AzimuthstddevdegEditFieldValueChanged(app, event)
            app.sample(app.CurrLayer+1).sigma_beta = str2double(app.AzimuthstddevdegEditField.Value);
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            % If Excel file is selected, specify the sheet and range.
            if(app.ExcelxlsxlsxButton.Value)
                Filename = app.FilepathEditField.Value;
                Sheet = app.SheetEditField.Value;
                Range = app.RangeEditField.Value;
                Import = readmatrix(Filename, 'Sheet', Sheet, 'Range', Range);
                
            % Otherwise, assume .txt /.csv and naively import all column data. 
            else
                Filename = app.FilepathEditField.Value;
                Import = readmatrix(Filename);
            end
            
            % Separate the columns into (x,y) data.
            app.x = Import(:,1);    app.y = Import(:,2);
            
            % Plot the imported data.
            semilogy(app.UIAxes, app.x, app.y, 'k-');
            axis(app.UIAxes, [min(app.x), max(app.x), -inf, inf]);
        end

        % Button pushed function: ClearData
        function ClearDataButtonPushed(app, event)
            app.x = 0;  app.y = 0;
            cla(app.UIAxes);
        end

        % Button pushed function: AddtoworkspaceButton2
        function AddtoworkspaceButton2Pushed(app, event)
            assignin('base', 'x', app.x);
            assignin('base', 'y', app.y);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.DoneLamp.Color = [1.00,0.00,0.00];          % Simulation processing
            app.DoneLampLabel.Text = 'Busy';
            drawnow
            
            addpath('C:\Users\sschaefe\OneDrive - NREL\Characterization\XRD\MCDD model\v2 Monoclinic');
            addpath('C:\Users\sschaefe\OneDrive - NREL\Characterization\XRD\MCDD model\v2 Monoclinic\Functions');
            
            % Pull in all the simulation parameters from the tab.
            orient = str2num(app.SubstrateorientationuvwEditField.Value);   % convert to vector
            offcut = app.OffcutdegEditField.Value*pi/180;                   % in radians
            delpsi = app.AzimuthwrtoffcutdegEditField.Value*pi/180;         % in radians
            plane = str2num(app.DiffractionplanehklEditField.Value);        % convert to vector
            lambda = app.XraywavelengthAngstromsEditField.Value;            % in Angstroms
            A = app.AmplitudeEditField.Value;                               % dimensionless
            
            thetaM = app.AngledegEditField.Value*pi/180;                    % in radians
            bounces = round(app.BouncesEditField.Value);
            if(app.Omega2ThetaButton.Value)
                ScanType = 'Omega-2Theta';
            else
                ScanType = 'Rocking';
            end

            N_sigma = app.StandarddeviationsEditField.Value;
            N_alpha = app.TiltcrystallitesEditField.Value;
            N_beta = app.AzimuthcrystallitesEditField.Value;
            DistAlpha = app.TiltdistributionDropDown.Value;
            DistBeta = app.AzimuthdistributionDropDown.Value;
            
            omstart = app.StartdegEditField.Value;                          % in degrees
            omend = app.EnddegEditField.Value;                              % in degrees
            omres = app.ResolutiondegEditField.Value;                       % in degrees
            app.omega = linspace(omstart, omend, round((omend - omstart)/omres)+1);     % in degrees
            app.omega = app.omega*pi/180;                                           % in radians
            Twotheta = app.TwoTheta.Value*pi/180;                           % in radians
            
            % Process the sample generated in the Sample tab.
            app.sampleout = SetupSample(app.sample);
            
            % Simulate the X-ray diffraction scan.
            [app.I, app.I0, app.lamella] = Xray(app.sampleout, app.omega, orient, plane, offcut, delpsi, ScanType, Twotheta, lambda, thetaM, bounces, N_sigma, N_alpha, N_beta, DistAlpha, DistBeta);
            app.I = A*app.I;    app.I0 = A*app.I0;
            assignin('base', 'omega', app.omega);
            assignin('base', 'I', app.I);
            assignin('base', 'I0', app.I0);
            assignin('base', 'lamella', app.lamella);
            
            % Plot the simulated scan, along with the measured data.
            semilogy(app.UIAxes, app.x + app.OffsetDataEditField.Value, app.y, 'k-'); hold(app.UIAxes, 'on');
            semilogy(app.UIAxes, app.omega*180/pi, app.I, 'r-');hold(app.UIAxes, 'off');
            if(min(app.x)>0)
                axis(app.UIAxes, [max([min(app.x), min(app.omega*180/pi)]), min([max(app.x), max(app.omega*180/pi)]), -inf, inf]);
            else
                axis(app.UIAxes, [min(app.omega*180/pi), max(app.omega*180/pi), -inf, inf]);
            end
            legend(app.UIAxes, 'Measurement', 'Simulation', 'Location', 'northeast');
            
            app.DoneLamp.Color = [0.00,1.00,0.00];          % Simulation complete
            app.DoneLampLabel.Text = {'Done'};
        end

        % Selection changed function: ScantypeButtonGroup
        function ScantypeButtonGroupSelectionChanged(app, event)
            if(app.Omega2ThetaButton.Value)
                omstart = app.StartdegEditField.Value;
                omend = app.EnddegEditField.Value;
                omcenter = (omstart + omend)/2;
                app.TwoTheta.Value = omcenter*2;
            end
        end

        % Value changed function: TwoTheta
        function TwoThetaValueChanged(app, event)
            if(app.Omega2ThetaButton.Value)
                omstart = app.StartdegEditField.Value;
                omend = app.EnddegEditField.Value;
                omcenter = (omstart + omend)/2;
                app.TwoTheta.Value = omcenter*2;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            if app.IncludeimporteddataCheckBox.Value
                % Construct the matrix for exporting.
                array1 = [app.x + app.OffsetDataEditField.Value, app.y];
                array2 = [transpose(app.omega*180/pi), app.I];
                L1 = size(array1);     L1 = L1(1);
                L2 = size(array2);     L2 = L2(1);
                
                % Pad the shorter array with zeros.
                if(L1 < L2)
                    array1 = padarray(array1, L2-L1, 0, 'post');
                elseif(L2 < L1)
                    array2 = padarray(array2, L1-L2, 0, 'post');
                end
                
                % Concatenate the arrays.
                exportarray = [array1, array2];
            else
                exportarray = [transpose(app.omega*180/pi), app.I];
            end
            
            % Write the data to the folder and filename.
            folder = app.FilepathEditField2.Value;
            filename = app.FilenameEditField.Value;
            writematrix(exportarray, strcat(folder, '\', filename));
        end

        % Menu selected function: PopoutfigureMenu
        function PopoutfigureMenuSelected(app, event)
            figure;
            
            % Plot the simulated scan, along with the measured data.
            semilogy(app.x + app.OffsetDataEditField.Value, app.y, 'k-'); hold on; grid on;
            semilogy(app.omega*180/pi, app.I, 'r-'); hold off;
            if(min(app.x)>0)
                axis([max([min(app.x), min(app.omega*180/pi)]), min([max(app.x), max(app.omega*180/pi)]), -inf, inf]);
            else
                axis([min(app.omega*180/pi), max(app.omega*180/pi), -inf, inf]);
            end
            legend('Measurement', 'Simulation', 'Location', 'northeast');
            xlabel('Omega (deg)');
            ylabel('X-ray intensity (a.u.)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportPanel = uipanel(app.UIFigure);
            app.ImportPanel.Title = 'Import';
            app.FiletypeButtonGroup = uibuttongroup(app.ImportPanel);
            app.FiletypeButtonGroup.Title = 'File type';
            app.ExcelxlsxlsxButton = uitogglebutton(app.FiletypeButtonGroup);
            app.ExcelxlsxlsxButton.Text = 'Excel (.xls, .xlsx)';
            app.ExcelxlsxlsxButton.Value = true;
            app.TexttxtcsvButton = uitogglebutton(app.FiletypeButtonGroup);
            app.TexttxtcsvButton.Text = 'Text (.txt, .csv)';
            app.ExcelfilesxlsxlsxPanel = uipanel(app.ImportPanel);
            app.ExcelfilesxlsxlsxPanel.Title = 'Excel files (.xls, .xlsx)';
            app.SheetEditFieldLabel = uilabel(app.ExcelfilesxlsxlsxPanel);
            app.SheetEditFieldLabel.Text = 'Sheet';
            app.SheetEditField = uieditfield(app.ExcelfilesxlsxlsxPanel, 'text');
            app.SheetEditField.Value = 'Fig 2';
            app.RangeEditFieldLabel = uilabel(app.ExcelfilesxlsxlsxPanel);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.ExcelfilesxlsxlsxPanel, 'text');
            app.RangeEditField.Value = 'B1:C441';
            app.AddtoworkspaceButton2 = uibutton(app.ImportPanel, 'push');
            app.AddtoworkspaceButton2.ButtonPushedFcn = createCallbackFcn(app, @AddtoworkspaceButton2Pushed, true);
            app.AddtoworkspaceButton2.Text = 'Add to workspace';
            app.ImportButton = uibutton(app.ImportPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClearData = uibutton(app.ImportPanel, 'push');
            app.ClearData.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearData.Text = 'Clear data';
            app.FilepathEditFieldLabel = uilabel(app.ImportPanel);
            app.FilepathEditFieldLabel.Text = 'File path';
            app.FilepathEditField = uieditfield(app.ImportPanel, 'text');
            app.FilepathEditField.Value = 'C:\Users\sschaefe\OneDrive - NREL\Characterization\XRD\MCDD model\v2 Monoclinic\Development plots\v2\Kaun et al comparison\Extracted data.xlsx';
            app.SimulatePanel = uipanel(app.UIFigure);
            app.SimulatePanel.Title = 'Simulate';
            app.UIAxes = uiaxes(app.SimulatePanel);
            title(app.UIAxes, 'Simulated scan')
            xlabel(app.UIAxes, 'Omega (deg)')
            ylabel(app.UIAxes, 'X-ray intensity (a.u.)')
            zlabel(app.UIAxes, 'Z')
            app.MonochromatorPanel = uipanel(app.SimulatePanel);
            app.MonochromatorPanel.Title = 'Monochromator';
            app.AngledegEditFieldLabel = uilabel(app.MonochromatorPanel);
            app.AngledegEditFieldLabel.Text = 'Angle (deg)';
            app.AngledegEditField = uieditfield(app.MonochromatorPanel, 'numeric');
            app.AngledegEditField.Limits = [0 Inf];
            app.AngledegEditField.ValueDisplayFormat = '%11.6g';
            app.AngledegEditField.Value = 22.6482;
            app.BouncesEditFieldLabel = uilabel(app.MonochromatorPanel);
            app.BouncesEditFieldLabel.Text = 'Bounces';
            app.BouncesEditField = uieditfield(app.MonochromatorPanel, 'numeric');
            app.BouncesEditField.Limits = [1 Inf];
            app.BouncesEditField.ValueDisplayFormat = '%11.1g';
            app.BouncesEditField.Value = 2;
            app.ScantypeButtonGroup = uibuttongroup(app.SimulatePanel);
            app.ScantypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScantypeButtonGroupSelectionChanged, true);
            app.ScantypeButtonGroup.Title = 'Scan type';
            app.Omega2ThetaButton = uitogglebutton(app.ScantypeButtonGroup);
            app.Omega2ThetaButton.Text = 'Omega/2Theta';
            app.Omega2ThetaButton.Value = true;
            app.RockingButton = uitogglebutton(app.ScantypeButtonGroup);
            app.RockingButton.Text = 'Rocking';
            app.OmegaincidentXrayanglePanel = uipanel(app.SimulatePanel);
            app.OmegaincidentXrayanglePanel.Title = 'Omega (incident X-ray angle)';
            app.StartdegEditFieldLabel = uilabel(app.OmegaincidentXrayanglePanel);
            app.StartdegEditFieldLabel.Text = 'Start (deg)';
            app.StartdegEditField = uieditfield(app.OmegaincidentXrayanglePanel, 'numeric');
            app.StartdegEditField.Value = 29.5;
            app.EnddegEditFieldLabel = uilabel(app.OmegaincidentXrayanglePanel);
            app.EnddegEditFieldLabel.Text = 'End (deg)';
            app.EnddegEditField = uieditfield(app.OmegaincidentXrayanglePanel, 'numeric');
            app.EnddegEditField.Value = 31.5;
            app.ResolutiondegEditFieldLabel = uilabel(app.OmegaincidentXrayanglePanel);
            app.ResolutiondegEditFieldLabel.Text = 'Resolution (deg)';
            app.ResolutiondegEditField = uieditfield(app.OmegaincidentXrayanglePanel, 'numeric');
            app.ResolutiondegEditField.Value = 0.001;
            app.ThetadegLabel = uilabel(app.OmegaincidentXrayanglePanel);
            app.ThetadegLabel.Text = '2Theta (deg)';
            app.TwoTheta = uieditfield(app.OmegaincidentXrayanglePanel, 'numeric');
            app.TwoTheta.ValueChangedFcn = createCallbackFcn(app, @TwoThetaValueChanged, true);
            app.SimulateButton = uibutton(app.SimulatePanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.MosaiccrystalparametersPanel = uipanel(app.SimulatePanel);
            app.MosaiccrystalparametersPanel.Title = 'Mosaic crystal parameters';
            app.StandarddeviationsEditFieldLabel = uilabel(app.MosaiccrystalparametersPanel);
            app.StandarddeviationsEditFieldLabel.Text = 'Standard deviations';
            app.StandarddeviationsEditField = uieditfield(app.MosaiccrystalparametersPanel, 'numeric');
            app.StandarddeviationsEditField.Limits = [0 Inf];
            app.StandarddeviationsEditField.Value = 3;
            app.TiltcrystallitesEditFieldLabel = uilabel(app.MosaiccrystalparametersPanel);
            app.TiltcrystallitesEditFieldLabel.Text = 'Tilt crystallites';
            app.TiltcrystallitesEditField = uieditfield(app.MosaiccrystalparametersPanel, 'numeric');
            app.TiltcrystallitesEditField.Limits = [0 Inf];
            app.TiltcrystallitesEditField.Value = 5;
            app.AzimuthcrystallitesEditFieldLabel = uilabel(app.MosaiccrystalparametersPanel);
            app.AzimuthcrystallitesEditFieldLabel.Text = 'Azimuth crystallites';
            app.AzimuthcrystallitesEditField = uieditfield(app.MosaiccrystalparametersPanel, 'numeric');
            app.AzimuthcrystallitesEditField.Limits = [0 Inf];
            app.AzimuthcrystallitesEditField.Value = 5;
            app.TiltdistributionDropDownLabel = uilabel(app.MosaiccrystalparametersPanel);
            app.TiltdistributionDropDownLabel.Text = {'Tilt'; 'distribution'};
            app.TiltdistributionDropDown = uidropdown(app.MosaiccrystalparametersPanel);
            app.TiltdistributionDropDown.Items = {'Gaussian', 'Uniform'};
            app.TiltdistributionDropDown.Value = 'Gaussian';
            app.AzimuthdistributionDropDownLabel = uilabel(app.MosaiccrystalparametersPanel);
            app.AzimuthdistributionDropDownLabel.Text = {'Azimuth'; 'distribution'};
            app.AzimuthdistributionDropDown = uidropdown(app.MosaiccrystalparametersPanel);
            app.AzimuthdistributionDropDown.Items = {'Gaussian', 'Uniform'};
            app.AzimuthdistributionDropDown.Value = 'Gaussian';
            app.SubstrateorientationuvwEditFieldLabel = uilabel(app.SimulatePanel);
            app.SubstrateorientationuvwEditFieldLabel.Text = 'Substrate orientation (u,v,w)';
            app.SubstrateorientationuvwEditField = uieditfield(app.SimulatePanel, 'text');
            app.SubstrateorientationuvwEditField.Value = '0 1 0';
            app.OffcutdegEditFieldLabel = uilabel(app.SimulatePanel);
            app.OffcutdegEditFieldLabel.Text = 'Offcut (deg)';
            app.OffcutdegEditField = uieditfield(app.SimulatePanel, 'numeric');
            app.OffcutdegEditField.Limits = [0 Inf];
            app.AzimuthwrtoffcutdegEditFieldLabel = uilabel(app.SimulatePanel);
            app.AzimuthwrtoffcutdegEditFieldLabel.Text = 'Azimuth w.r.t. offcut (deg)';
            app.AzimuthwrtoffcutdegEditField = uieditfield(app.SimulatePanel, 'numeric');
            app.AzimuthwrtoffcutdegEditField.Limits = [0 Inf];
            app.DiffractionplanehklEditFieldLabel = uilabel(app.SimulatePanel);
            app.DiffractionplanehklEditFieldLabel.Text = 'Diffraction plane (h,k,l)';
            app.DiffractionplanehklEditField = uieditfield(app.SimulatePanel, 'text');
            app.DiffractionplanehklEditField.Value = '0 2 0';
            app.XraywavelengthAngstromsEditFieldLabel = uilabel(app.SimulatePanel);
            app.XraywavelengthAngstromsEditFieldLabel.Text = 'X-ray wavelength (Angstroms)';
            app.XraywavelengthAngstromsEditField = uieditfield(app.SimulatePanel, 'numeric');
            app.XraywavelengthAngstromsEditField.Limits = [0 Inf];
            app.XraywavelengthAngstromsEditField.ValueDisplayFormat = '%11.5g';
            app.XraywavelengthAngstromsEditField.Value = 1.54056;
            app.AmplitudeauLabel = uilabel(app.SimulatePanel);
            app.AmplitudeauLabel.Text = 'Amplitude (a.u.)';
            app.AmplitudeEditField = uieditfield(app.SimulatePanel, 'numeric');
            app.AmplitudeEditField.Limits = [0 Inf];
            app.AmplitudeEditField.ValueDisplayFormat = '%11.3g';
            app.AmplitudeEditField.Value = 10000000;
            app.DoneLampLabel = uilabel(app.SimulatePanel);
            app.DoneLampLabel.Text = 'Done';
            app.DoneLamp = uilamp(app.SimulatePanel);
            app.OffsetdatadegLabel = uilabel(app.SimulatePanel);
            app.OffsetdatadegLabel.Text = 'Offset data (deg)';
            app.OffsetDataEditField = uieditfield(app.SimulatePanel, 'numeric');
            app.OffsetDataEditField.ValueDisplayFormat = '%11.3g';
            app.SamplePanel = uipanel(app.UIFigure);
            app.SamplePanel.Title = 'Sample';
            app.AddlayerButton = uibutton(app.SamplePanel, 'push');
            app.AddlayerButton.ButtonPushedFcn = createCallbackFcn(app, @AddlayerButtonPushed, true);
            app.AddlayerButton.Text = 'Add layer';
            app.RemovelayerButton = uibutton(app.SamplePanel, 'push');
            app.RemovelayerButton.ButtonPushedFcn = createCallbackFcn(app, @RemovelayerButtonPushed, true);
            app.RemovelayerButton.Text = 'Remove layer';
            app.AddSLButton = uibutton(app.SamplePanel, 'push');
            app.AddSLButton.ButtonPushedFcn = createCallbackFcn(app, @AddSLButtonPushed, true);
            app.AddSLButton.Text = 'Add superlattice';
            app.Addtoworkspace = uibutton(app.SamplePanel, 'push');
            app.Addtoworkspace.ButtonPushedFcn = createCallbackFcn(app, @AddtoworkspaceButtonPushed, true);
            app.Addtoworkspace.Text = 'Add to workspace';
            app.SamplestackupListBoxLabel = uilabel(app.SamplePanel);
            app.SamplestackupListBoxLabel.Text = {'Sample'; 'stackup'};
            app.SamplestackupListBox = uilistbox(app.SamplePanel);
            app.SamplestackupListBox.Items = {'Substrate'};
            app.SamplestackupListBox.ItemsData = 0;
            app.SamplestackupListBox.ValueChangedFcn = createCallbackFcn(app, @SamplestackupListBoxValueChanged, true);
            app.SamplestackupListBox.Value = 0;
            app.CationcompositionLabel = uilabel(app.SamplePanel);
            app.CationcompositionLabel.Text = 'Cation composition';
            app.InterfaceoffsetlayerCheckBox = uicheckbox(app.SamplePanel);
            app.InterfaceoffsetlayerCheckBox.ValueChangedFcn = createCallbackFcn(app, @InterfaceoffsetlayerCheckBoxValueChanged, true);
            app.InterfaceoffsetlayerCheckBox.Text = 'Interface offset layer';
            app.AlmolefractionEditFieldLabel = uilabel(app.SamplePanel);
            app.AlmolefractionEditFieldLabel.Text = 'Al mole fraction';
            app.AlmolefractionEditField = uieditfield(app.SamplePanel, 'text');
            app.AlmolefractionEditField.ValueChangedFcn = createCallbackFcn(app, @AlmolefractionEditFieldValueChanged, true);
            app.AlmolefractionEditField.Value = '0';
            app.GamolefractionEditFieldLabel = uilabel(app.SamplePanel);
            app.GamolefractionEditFieldLabel.Text = 'Ga mole fraction';
            app.GamolefractionEditField = uieditfield(app.SamplePanel, 'text');
            app.GamolefractionEditField.Editable = 'off';
            app.GamolefractionEditField.Value = '1';
            app.InmolefractionEditFieldLabel = uilabel(app.SamplePanel);
            app.InmolefractionEditFieldLabel.Text = 'In mole fraction';
            app.InmolefractionEditField = uieditfield(app.SamplePanel, 'text');
            app.InmolefractionEditField.ValueChangedFcn = createCallbackFcn(app, @InmolefractionEditFieldValueChanged, true);
            app.InmolefractionEditField.Value = '0';
            app.LayerpropertiesLabel = uilabel(app.SamplePanel);
            app.LayerpropertiesLabel.Text = 'Layer properties';
            app.ThicknessnmEditFieldLabel = uilabel(app.SamplePanel);
            app.ThicknessnmEditFieldLabel.Text = 'Thickness (nm)';
            app.ThicknessnmEditField = uieditfield(app.SamplePanel, 'text');
            app.ThicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessnmEditFieldValueChanged, true);
            app.ThicknessnmEditField.Value = '600000';
            app.EpilayertiltdegEditFieldLabel = uilabel(app.SamplePanel);
            app.EpilayertiltdegEditFieldLabel.Text = 'Epilayer tilt (deg)';
            app.EpilayertiltdegEditField = uieditfield(app.SamplePanel, 'text');
            app.EpilayertiltdegEditField.ValueChangedFcn = createCallbackFcn(app, @EpilayertiltdegEditFieldValueChanged, true);
            app.EpilayertiltdegEditField.Value = '0';
            app.RelaxationEditFieldLabel = uilabel(app.SamplePanel);
            app.RelaxationEditFieldLabel.Text = 'Relaxation (%)';
            app.RelaxationEditField = uieditfield(app.SamplePanel, 'text');
            app.RelaxationEditField.ValueChangedFcn = createCallbackFcn(app, @RelaxationEditFieldValueChanged, true);
            app.RelaxationEditField.Value = '0';
            app.SLrepeatsEditFieldLabel = uilabel(app.SamplePanel);
            app.SLrepeatsEditFieldLabel.Text = 'SL repeats';
            app.SLrepeatsEditField = uieditfield(app.SamplePanel, 'text');
            app.SLrepeatsEditField.ValueChangedFcn = createCallbackFcn(app, @SLrepeatsEditFieldValueChanged, true);
            app.SLrepeatsEditField.Value = '1';
            app.MosaiccrystalpropertiesLabel = uilabel(app.SamplePanel);
            app.MosaiccrystalpropertiesLabel.Text = 'Mosaic crystal properties';
            app.TiltstddevdegEditFieldLabel = uilabel(app.SamplePanel);
            app.TiltstddevdegEditFieldLabel.Text = 'Tilt std. dev. (deg)';
            app.TiltstddevdegEditField = uieditfield(app.SamplePanel, 'text');
            app.TiltstddevdegEditField.ValueChangedFcn = createCallbackFcn(app, @TiltstddevdegEditFieldValueChanged, true);
            app.TiltstddevdegEditField.Value = '0';
            app.AzimuthstddevdegEditFieldLabel = uilabel(app.SamplePanel);
            app.AzimuthstddevdegEditFieldLabel.Text = 'Azimuth std. dev. (deg)';
            app.AzimuthstddevdegEditField = uieditfield(app.SamplePanel, 'text');
            app.AzimuthstddevdegEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthstddevdegEditFieldValueChanged, true);
            app.AzimuthstddevdegEditField.Value = '0';
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Title = 'Export';
            app.IncludeimporteddataCheckBox = uicheckbox(app.ExportPanel);
            app.IncludeimporteddataCheckBox.Text = 'Include imported data?';
            app.IncludeimporteddataCheckBox.Value = true;
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FilepathEditField_2Label = uilabel(app.ExportPanel);
            app.FilepathEditField_2Label.Text = 'File path';
            app.FilepathEditField2 = uieditfield(app.ExportPanel, 'text');
            app.FilepathEditField2.Value = 'C:\Users\sschaefe\OneDrive - NREL\Characterization\XRD\MCDD model\v2 Monoclinic';
            app.FilenameEditFieldLabel = uilabel(app.ExportPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.ExportPanel, 'text');
            app.FilenameEditField.Value = 'export.xlsx';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.PopoutfigureMenu = uimenu(app.ContextMenu);
            app.PopoutfigureMenu.MenuSelectedFcn = createCallbackFcn(app, @PopoutfigureMenuSelected, true);
            app.PopoutfigureMenu.Text = 'Pop out figure';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XRayApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end